classdef TRY2EYE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image2    matlab.ui.control.Image
        PButton   matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end


    properties (Access = private)
        EOG % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EOG = [0.5 1 0.2 -0.3 -0.8 -0.1 0.4 1 0.2 -0.5];
        end

        % Button pushed function: PButton
        function playPUSHED(app, event)
            if isempty(app.EOG)
                uialert(app.UIFigure,'No hay señal EOG cargada','Señal vacía');
                return
            end

            step = 5;            % intensidad base por muestra
            dt   = 0.05;         % tiempo entre muestras (s)

            % límites para que la imagen no salga del UIFigure
            figPos = app.UIFigure.Position; % [x y w h]
            figW = figPos(3);
            figH = figPos(4);

            for i = 1:length(app.EOG)
                value = app.EOG(i);
                pos = app.Image.Position;   % [x y w h]

                % Movimiento: positivo -> izquierda, negativo -> derecha
                if value > 0
                    pos(1) = pos(1) - step * abs(value);
                elseif value < 0
                    pos(1) = pos(1) + step * abs(value);
                end

                % Limitar dentro de la ventana
                if pos(1) < 0
                    pos(1) = 0;
                end
                if pos(1) + pos(3) > figW
                    pos(1) = figW - pos(3);
                end

                app.Image.Position = pos;
                drawnow;            % actualiza GUI inmediatamente
                pause(dt);          % controla velocidad de reproducción
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'EYEL.png';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @playPUSHED, true);
            app.PButton.Text = 'P';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'BROWL.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRY2EYE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end