classdef sepa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EventsAnalisisWindow        matlab.ui.container.Panel
        EventsAnalisisGrid          matlab.ui.container.GridLayout
        EventsTable                 matlab.ui.control.Table
        TotalEventsLBL              matlab.ui.control.Label
        EventsAnalisisLBL           matlab.ui.control.Label
        GoBackButtonEvents          matlab.ui.control.Button
        AnalisisWindow              matlab.ui.container.Panel
        AnalisisGrid                matlab.ui.container.GridLayout
        ObservacionesLBL            matlab.ui.control.Label
        ObservacionesNamelbl        matlab.ui.control.Label
        EventsAnalisisButton        matlab.ui.control.Button
        TypeAnalisisLabel           matlab.ui.control.Label
        AnalisisTypeDropDown        matlab.ui.control.DropDown
        ExportButton                matlab.ui.control.Button
        miradasDerechaLbl           matlab.ui.control.Label
        miradasIzquierdaLbl         matlab.ui.control.Label
        UITableResultadosDerecha    matlab.ui.control.Table
        FatigaLabel                 matlab.ui.control.Label
        PatientInfoLabel            matlab.ui.control.Label
        FATIGAname                  matlab.ui.control.Label
        DATOSDELPACIENTELabel       matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ANLISISPACIENTELabel        matlab.ui.control.Label
        UITableResultadosIzquierda  matlab.ui.control.Table
        GoBackButtonAnalisis        matlab.ui.control.Button
        Mainmenu                    matlab.ui.container.Panel
        MainMenuGrid                matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        DataButton                  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        CargardatosButton           matlab.ui.control.Button
        Mainplot                    matlab.ui.control.UIAxes
        EyesWindow                  matlab.ui.container.Panel
        EyesGrid                    matlab.ui.container.GridLayout
        EyePanelR                   matlab.ui.container.Panel
        EYER                        matlab.ui.control.Image
        PlayButton                  matlab.ui.control.Button
        EyePanelL                   matlab.ui.container.Panel
        EYEL                        matlab.ui.control.Image
        GoBackButtonEyes            matlab.ui.control.Button
        ExpandedPlot                matlab.ui.control.UIAxes
        SubplotsWindow              matlab.ui.container.Panel
        SubplotsGrid                matlab.ui.container.GridLayout
        TipoPruebasDropDown         matlab.ui.control.DropDown
        PruebasLabel                matlab.ui.control.Label
        PruebasDropDown             matlab.ui.control.DropDown
        TestButton                  matlab.ui.control.Button
        AnalisisButton              matlab.ui.control.Button
        IntroButton                 matlab.ui.control.Button
        RestButton                  matlab.ui.control.Button
        intro                       matlab.ui.control.UIAxes
        test                        matlab.ui.control.UIAxes
        rest                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        blocks
        Introplot  matlab.graphics.chart.primitive.Line
        Testplot   matlab.graphics.chart.primitive.Line
        Restplot   matlab.graphics.chart.primitive.Line
        index      double

        % >>> Info del paciente <<<
        PatientID string
        PatientGender string
        PatientAge double
        PatientParalysis string
        PatientNIHSS double
        PatientMBI double
        PatientmRS double
        DataLoaded = false;
        % Tabla con los datos del TSV
        ParticipantsTable table
        EOG
        EventLocations
        EventDirections
        FilteredEOG
        EventStartIdx
        EventEndIdx
        ChosenPart
        isPlaying = false;
        EYEL_initialPos
        EYER_initialPos
        f
        EOG_full
        LegendLeft
        LegendRight
        T_events
    end

    methods (Access = private)
        function ExpandedView(app,id)
            % Ocultamos subplots y mostramos la ventana ampliada
            app.SubplotsWindow.Visible = 'off';
            app.EyesWindow.Visible     = 'on';

            % Usamos el bloque actualmente seleccionado en el dropdown
            n = app.index;   % este lo pones en ChoosePrueba

            % Elegimos qué sub-bloque queremos (intro/test/rest)
            switch id
                case 0   % Introduction
                    tBlock   = app.blocks(n).t{1};
                    sigBlock = app.blocks(n).sig{1};
                    app.ExpandedPlot.Title.String = "Introduction (0-2s)";
                    app.ChosenPart = 1;

                case 1   % Test
                    tBlock   = app.blocks(n).t{2};
                    sigBlock = app.blocks(n).sig{2};
                    app.ExpandedPlot.Title.String = "Motor Imagery Test (2-6s)";
                    app.ChosenPart = 2;

                case 2   % Rest
                    tBlock   = app.blocks(n).t{3};
                    sigBlock = app.blocks(n).sig{3};
                    app.ExpandedPlot.Title.String = "Rest (6-8s)";
                    app.ChosenPart = 3;
            end

            % Limpiamos y dibujamos la señal en el eje ampliado
            cla(app.ExpandedPlot);
            plot(app.ExpandedPlot, tBlock, sigBlock);
            ensureLegend(app);
            hold(app.ExpandedPlot,'on');

            % Volvemos a dibujar las flechas de ese mismo bloque
            drawArrows(app,4, tBlock, sigBlock);
            drawEventLimits(app,tBlock);
            % Activar grid
            grid(app.ExpandedPlot, 'on');

            % Color de las líneas del grid (gris clarito)
            app.ExpandedPlot.GridColor = [0.8 0.8 0.8];   % RGB (200/255)
            app.ExpandedPlot.GridAlpha = 0.4;             % Transparencia
            hold(app.ExpandedPlot,'off');
        end
        function ensureLegend(app)

            % --- Eliminar si ya existen (evita duplicados) ---
            if isfield(app,'LegendLeft') && isvalid(app.LegendLeft)
                delete(app.LegendLeft)
            end
            if isfield(app,'LegendRight') && isvalid(app.LegendRight)
                delete(app.LegendRight)
            end
            x0 = 0.92;   % mueve este valor si lo quieres más a la derecha
            y0 = 0.94;
            dy = 0.08;   % separación vertical

            app.LegendLeft = text(app.ExpandedPlot, x0, y0, '▴ Left', ...
                'Units','normalized', ...
                'HorizontalAlignment','left', ...
                'VerticalAlignment','top', ...
                'FontSize', 16, ...
                'FontWeight','bold', ...
                'Color', 'red', ...
                'BackgroundColor','none', ...
                'Margin', 2);

            % --- Línea 2: Right (AZUL) ---
            app.LegendRight = text(app.ExpandedPlot, x0, y0-dy, '▾ Right', ...
                'Units','normalized', ...
                'HorizontalAlignment','left', ...
                'VerticalAlignment','top', ...
                'FontSize', 16, ...
                'FontWeight','bold', ...
                'Color', 'blue', ...
                'BackgroundColor','none', ...
                'Margin', 2);

        end


        function T_summary = calculateEOGMetrics(app,fs, patientName)
            % T_summary = calculateEOGMetrics(EOG, fs, patientName)
            %
            % NOTA: Se asume que app.EOG es la señal diferencial.

            app.EOG = double(app.EOG);

            % ... [código de filtrado, velocidad, y detección de picos (sin cambios)] ...

            fcuts = [0.1 15];
            [b,a] = butter(3, fcuts/(fs/2));
            EOGf = filtfilt(b,a,app.EOG);

            vel = [0; diff(EOGf)] * fs;
            absVel = abs(vel);

            if length(absVel) < 3
                warning('Señal de EOG demasiado corta.');
                T_summary = table();
                return;
            end

            umbralVel = prctile(absVel(absVel > 40), 98);
            [pkV, locV] = findpeaks(absVel, 'MinPeakHeight', umbralVel, 'MinPeakDistance', round(0.05*fs));

            n = length(locV);

            %% 🟥 --- MANEJO DE CASO SIN EVENTOS (n = 0) --- 🟥
            if n == 0
                warning('No se detectaron movimientos oculares.');
                % Creamos la tabla con tipos consistentes para no romper UpdateResultsTable
                T_summary = table(string(patientName), 0, 0, 0, ...
                    0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, ...
                    "No eventos", "No eventos", "No eventos", "No eventos", ...
                    'VariableNames', {'Patient','nEvents','miradasDer','miradasIzq',...
                    'velHighPct_Der','velNormalPct_Der','velLowPct_Der','velHighPct_Izq','velNormalPct_Izq','velLowPct_Izq',...
                    'ampHighPct_Der','ampNormalPct_Der','ampLowPct_Der','ampHighPct_Izq','ampNormalPct_Izq','ampLowPct_Izq',...
                    'durLongPct_Der','durNormalPct_Der','durShortPct_Der','durLongPct_Izq','durNormalPct_Izq','durShortPct_Izq',...
                    'velObs','ampObs','durObs','dirObs'});
                T_summary.Patient = string(patientName);
                return;
            end

            % ... [resto del código de extracción de eventos (pkV, amp_all, dir_all, etc.) sin cambios] ...
            peakVel_all = zeros(1,n);
            amp_all     = zeros(1,n);
            dur_all     = zeros(1,n);
            dir_all     = zeros(1,n);

            start_all = zeros(1,n);
            end_all = zeros(1,n);

            baseline_all = zeros(1,n);

            for i = 1:n
                pk = locV(i);
                thrStart = 0.2 * pkV(i);

                startIdx = pk;
                while startIdx > 1 && absVel(startIdx) > thrStart
                    startIdx = startIdx - 1;
                end

                endIdx = pk;
                while endIdx < length(absVel) && absVel(endIdx) > thrStart
                    endIdx = endIdx + 1;
                end

                baseline = mean(EOGf(max(startIdx-20,1):startIdx));
                amp2 = EOGf(pk) - baseline;
                dur  = (endIdx - startIdx) / fs;

                % Dirección
                umbralDir = 0.02;
                if abs(amp2) < umbralDir || dur < 0.03 || dur > 1.0
                    dir = 0;
                else
                    dir = sign(amp2);  % +1 izquierda, -1 derecha
                end

                peakVel_all(i) = pkV(i);
                amp_all(i)     = abs(amp2);
                dur_all(i)     = dur;
                dir_all(i)     = dir;

                start_all(i)   = startIdx;
                end_all(i)     = endIdx;
                baseline_all(i)= baseline;
            end


            %% 🟥 --- IDENTIFICAR EVENTOS POR DIRECCIÓN --- 🟥
            idxDer = dir_all == 1;
            idxIzq = dir_all == -1;
            nEventsDer = sum(idxDer);
            nEventsIzq = sum(idxIzq);
            % Filtrar los arrays para cada dirección
            velDer = peakVel_all(idxDer);
            velIzq = peakVel_all(idxIzq);
            ampDer = amp_all(idxDer);
            ampIzq = amp_all(idxIzq);
            durDer = dur_all(idxDer);
            durIzq = dur_all(idxIzq);

            %% 🟥 --- PERCENTILE LIMITS (GLOBALES) --- 🟥
            velLow  = prctile(peakVel_all, 33);
            velHigh = prctile(peakVel_all, 66);
            ampLow  = prctile(amp_all, 33);
            ampHigh = prctile(amp_all, 66);
            durLow  = prctile(dur_all, 33);
            durHigh = prctile(dur_all, 66);

            %% 🟥 --- CATEGORIZACIÓN (USANDO LÍMITES GLOBALES) --- 🟥
            velCatDer = discretize(velDer, [0 velLow velHigh inf], ["baja","normal","alta"]);
            ampCatDer = discretize(ampDer, [0 ampLow ampHigh inf], ["baja","normal","alta"]);
            durCatDer = discretize(durDer, [0 durLow durHigh inf], ["corta","normal","larga"]);
            velCatIzq = discretize(velIzq, [0 velLow velHigh inf], ["baja","normal","alta"]);
            ampCatIzq = discretize(ampIzq, [0 ampLow ampHigh inf], ["baja","normal","alta"]);
            durCatIzq = discretize(durIzq, [0 durLow durHigh inf], ["corta","normal","larga"]);

            %% 🟥 --- CÁLCULO DE PORCENTAJES ASIMÉTRICOS (INCLUYENDO NORMAL) --- 🟥
            velHighPctDer = sum(velCatDer == "alta")/max(1,nEventsDer)*100;
            velNormalPctDer = sum(velCatDer == "normal")/max(1,nEventsDer)*100;
            velLowPctDer  = sum(velCatDer == "baja")/max(1,nEventsDer)*100;
            velHighPctIzq = sum(velCatIzq == "alta")/max(1,nEventsIzq)*100;
            velNormalPctIzq = sum(velCatIzq == "normal")/max(1,nEventsIzq)*100;
            velLowPctIzq  = sum(velCatIzq == "baja")/max(1,nEventsIzq)*100;
            ampHighPctDer = sum(ampCatDer == "alta")/max(1,nEventsDer)*100;
            ampNormalPctDer = sum(ampCatDer == "normal")/max(1,nEventsDer)*100;
            ampLowPctDer  = sum(ampCatDer == "baja")/max(1,nEventsDer)*100;
            ampHighPctIzq = sum(ampCatIzq == "alta")/max(1,nEventsIzq)*100;
            ampNormalPctIzq = sum(ampCatIzq == "normal")/max(1,nEventsIzq)*100;
            ampLowPctIzq  = sum(ampCatIzq == "baja")/max(1,nEventsIzq)*100;
            durLongPctDer = sum(durCatDer == "larga")/max(1,nEventsDer)*100;
            durNormalPctDer = sum(durCatDer == "normal")/max(1,nEventsDer)*100;
            durShortPctDer= sum(durCatDer == "corta")/max(1,nEventsDer)*100;
            durLongPctIzq = sum(durCatIzq == "larga")/max(1,nEventsIzq)*100;
            durNormalPctIzq = sum(durCatIzq == "normal")/max(1,nEventsIzq)*100;
            durShortPctIzq= sum(durCatIzq == "corta")/max(1,nEventsIzq)*100;

            %% 🟥 --- TABLA POR EVENTO (T_events) --- 🟥

            EventID    = (1:n)';
            StartTime = start_all(:) / fs;
            PeakTime  = locV(:) / fs;
            EndTime   = end_all(:) / fs;
            Duration  = dur_all(:);
            Latency   = (locV(:) - start_all(:)) / fs;
            PeakVel   = peakVel_all(:);
            Amplitude = amp_all(:);

            Direction = strings(n,1);
            Direction(dir_all ==  1) = "Derecha";
            Direction(dir_all == -1) = "Izquierda";
            Direction(dir_all ==  0) = "Centro";

            % Inicializar categorías
            CatVel = strings(n,1);
            CatAmp = strings(n,1);
            CatDur = strings(n,1);
            kDer = 0;
            kIzq = 0;

            for i = 1:n
                if dir_all(i) == 1
                    kDer = kDer + 1;
                    CatVel(i) = velCatDer(kDer);
                    CatAmp(i) = ampCatDer(kDer);
                    CatDur(i) = durCatDer(kDer);
                elseif dir_all(i) == -1
                    kIzq = kIzq + 1;
                    CatVel(i) = velCatIzq(kIzq);
                    CatAmp(i) = ampCatIzq(kIzq);
                    CatDur(i) = durCatIzq(kIzq);
                else
                    CatVel(i) = "NA";
                    CatAmp(i) = "NA";
                    CatDur(i) = "NA";
                end
            end
            Baseline = baseline_all(:);
            t_events = table(EventID, StartTime, PeakTime, EndTime, Duration, Latency, ...
                PeakVel, Amplitude, Baseline, Direction, CatVel, CatAmp, CatDur);
            app.T_events = t_events;

            %% 🟥 --- MÉTRICAS ADICIONALES POR DIRECCIÓN --- 🟥

            % --- Latencias por dirección ---
            lat_all = (locV - start_all) / fs;

            latDer = lat_all(idxDer);
            latIzq = lat_all(idxIzq);

            latMeanDer = mean(latDer,'omitnan');
            latStdDer  = std(latDer,'omitnan');

            latMeanIzq = mean(latIzq,'omitnan');
            latStdIzq  = std(latIzq,'omitnan');

            if nEventsDer > 0
                rangeDer = max(EOGf(locV(idxDer))) - min(EOGf(locV(idxDer)));
                rmsDer = rms(EOGf(locV(idxDer)));
            else
                rangeDer = NaN;
                rmsDer = NaN;
            end

            if nEventsIzq > 0
                rangeIzq = max(EOGf(locV(idxIzq))) - min(EOGf(locV(idxIzq)));
                rmsIzq = rms(EOGf(locV(idxIzq)));
            else
                rangeIzq = NaN;
                rmsIzq = NaN;
            end

            % --- Índice de simetría (global) ---
            symIndex = (nEventsDer - nEventsIzq) / max(1, nEventsDer + nEventsIzq);

            % --- Ratios (globales, interpretables) ---
            velRatio = mean(velDer,'omitnan') / max(mean(velIzq,'omitnan'), eps);
            ampRatio = mean(ampDer,'omitnan') / max(mean(ampIzq,'omitnan'), eps);


            %% --- Summary ---
            nEvents = n;
            nDerecha  = nEventsDer;
            nIzquierda = nEventsIzq;
            totalMiradas = nDerecha + nIzquierda;

            velObs = "velocidad normal";
            ampObs = "amplitudes normales";
            durObs = "duraciones normales";
            dirObs = "predominancia normal";

            if (velHighPctDer + velHighPctIzq)/2 > 30
                velObs = "alta frecuencia → posible hiperreflexia ocular";
            end
            if (ampLowPctDer + ampLowPctIzq)/2 > 20
                ampObs = "amplitudes bajas frecuentes → posible paresia ocular";
            end
            if (durLongPctDer + durLongPctIzq)/2 > 20
                durObs = "duración larga frecuente → posible déficit neuromotor";
            end

            if totalMiradas > 0
                if (nDerecha/totalMiradas - nIzquierda/totalMiradas) > 0.20
                    dirObs = "predominante derecha";
                elseif (nDerecha/totalMiradas - nIzquierda/totalMiradas) < -0.20
                    dirObs = "predominante izquierda";
                end
            end
            % === GUARDAR EVENTOS PARA USARLOS EN LA GRAFICA ===
            app.EventLocations = locV;      % índices de los eventos
            app.EventDirections = dir_all;  % 1 = derecha, -1 = izquierda
            app.FilteredEOG = EOGf;         % señal filtrada para grafica
            app.EventStartIdx = start_all;
            app.EventEndIdx = end_all;
            %% 🟥 --- Output table (FINAL) --- 🟥
            % Usamos string(patientName) para la columna Patient, y el resto son doubles/strings
            %% 🟥 --- Output table (FINAL) --- 🟥
            T_summary = table(string(patientName), nEvents, nDerecha, nIzquierda, ...
                velHighPctDer, velNormalPctDer, velLowPctDer, velHighPctIzq, velNormalPctIzq, velLowPctIzq, ...
                ampHighPctDer, ampNormalPctDer, ampLowPctDer, ampHighPctIzq, ampNormalPctIzq, ampLowPctIzq, ...
                durLongPctDer, durNormalPctDer, durShortPctDer, durLongPctIzq, durNormalPctIzq, durShortPctIzq, ...
                rangeDer, rangeIzq, rmsDer, rmsIzq, ...
                latMeanDer, latStdDer, latMeanIzq, latStdIzq, ...
                symIndex, velRatio, ampRatio, ...
                velObs, ampObs, durObs, dirObs, ...
                'VariableNames', {'Patient','nEvents','miradasDer','miradasIzq', ...
                'velHighPct_Der','velNormalPct_Der','velLowPct_Der','velHighPct_Izq','velNormalPct_Izq','velLowPct_Izq', ...
                'ampHighPct_Der','ampNormalPct_Der','ampLowPct_Der','ampHighPct_Izq','ampNormalPct_Izq','ampLowPct_Izq', ...
                'durLongPct_Der','durNormalPct_Der','durShortPct_Der','durLongPct_Izq','durNormalPct_Izq','durShortPct_Izq', ...
                'rangeDer','rangeIzq','rmsDer','rmsIzq', ...
                'latMeanDer','latStdDer','latMeanIzq','latStdIzq', ...
                'symIndex','velRatio','ampRatio', ...
                'velObs','ampObs','durObs','dirObs'});
        end
        function UpdateResultsTable(app, patient_data)
            % --- Columnas por ojo ---
            leftCols = {'miradasIzq','velHighPct_Izq','velNormalPct_Izq','velLowPct_Izq', ...
                'ampHighPct_Izq','ampNormalPct_Izq','ampLowPct_Izq', ...
                'durLongPct_Izq','durNormalPct_Izq','durShortPct_Izq'};
            rightCols = {'miradasDer','velHighPct_Der','velNormalPct_Der','velLowPct_Der', ...
                'ampHighPct_Der','ampNormalPct_Der','ampLowPct_Der', ...
                'durLongPct_Der','durNormalPct_Der','durShortPct_Der'};
            numCols = numel(leftCols);

            % --- Extraer datos de cada ojo ---
            if all(ismember(leftCols, patient_data.Properties.VariableNames))
                datos_izquierda = table2array(patient_data(:, leftCols));
            else
                datos_izquierda = nan(1,numCols);
            end
            if numel(datos_izquierda) < numCols
                datos_izquierda = [datos_izquierda, nan(1,numCols - numel(datos_izquierda))];
            end

            if all(ismember(rightCols, patient_data.Properties.VariableNames))
                datos_derecha = table2array(patient_data(:, rightCols));
            else
                datos_derecha = nan(1,numCols);
            end
            if numel(datos_derecha) < numCols
                datos_derecha = [datos_derecha, nan(1,numCols - numel(datos_derecha))];
            end

            rowNames = {'Alto','Normal','Bajo'};
            colNames = {'Amplitud (%)','Velocidad (%)','Duracion (%)'};

            MIZQ = "Miradas a la izquierda: " + string(datos_izquierda(1));
            MDER = "Miradas a la derecha: "  + string(datos_derecha(1));

            %% --- Tabla Izquierda ---
            if isprop(app, 'UITableResultadosIzquierda') && isvalid(app.UITableResultadosIzquierda)
                d = datos_izquierda(2:end);
                tabla_izq = [
                    d(4)  d(1)  d(7);   % Alto
                    d(5)  d(2)  d(8);   % Normal
                    d(6)  d(3)  d(9)    % Bajo
                    ];
                tabla_izq_cell = num2cell(tabla_izq);

                % --- Columnas globales por ojo ---
                globalsIzq = {'rangeIzq','rmsIzq','latMeanIzq','latStdIzq'};
                extraCols = repmat({''},3,numel(globalsIzq));
                for i = 1:numel(globalsIzq)
                    if ismember(globalsIzq{i}, patient_data.Properties.VariableNames)
                        v = patient_data.(globalsIzq{i});
                    else
                        v = NaN;
                    end
                    if ~isnan(v)
                        % Rangos definidos según señal EOG
                        switch globalsIzq{i}
                            case 'rangeIzq'
                                if v < 100
                                    extraCols{3,i} = v;
                                elseif v <= 250
                                    extraCols{2,i} = v;
                                else
                                    extraCols{1,i} = v;
                                end
                            case 'rmsIzq'
                                if v < 50
                                    extraCols{3,i} = v;
                                elseif v <= 100
                                    extraCols{2,i} = v;
                                else
                                    extraCols{1,i} = v;
                                end
                            case 'latMeanIzq'
                                if v < 200
                                    extraCols{1,i} = v; % más rápido → Alto
                                elseif v <= 300
                                    extraCols{2,i} = v;
                                else
                                    extraCols{3,i} = v;
                                end
                            case 'latStdIzq'
                                if v < 30
                                    extraCols{1,i} = v;
                                elseif v <= 60
                                    extraCols{2,i} = v;
                                else
                                    extraCols{3,i} = v;
                                end
                        end
                    end
                end

                % --- Columnas compartidas ---
                sharedGlobals = {'symIndex','velRatio','ampRatio'};
                extraShared = repmat({''},3,numel(sharedGlobals));
                for i = 1:numel(sharedGlobals)
                    if ismember(sharedGlobals{i}, patient_data.Properties.VariableNames)
                        v = patient_data.(sharedGlobals{i});
                    else
                        v = NaN;
                    end
                    if ~isnan(v)
                        switch sharedGlobals{i}
                            case 'symIndex'
                                if v < 0.33
                                    extraShared{3,i} = v;
                                elseif v <= 0.66
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                            case 'velRatio'
                                if v < 0.8
                                    extraShared{3,i} = v;
                                elseif v <= 1.2
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                            case 'ampRatio'
                                if v < 0.8
                                    extraShared{3,i} = v;
                                elseif v <= 1.2
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                        end
                    end
                end

                tabla_izq_ext = [tabla_izq_cell, extraCols, extraShared];
                colNames_ext = [colNames, "Rango P2P (µV)","RMS (µV)","Latencia media (s)","Latencia STD (s)", "Indice Simetria %","Ratio Velocidad","Ratio Amplitud"];

                app.UITableResultadosIzquierda.Data = tabla_izq_ext;
                app.UITableResultadosIzquierda.ColumnName = colNames_ext;
                app.UITableResultadosIzquierda.RowName = rowNames;
                app.UITableResultadosIzquierda.ColumnWidth = 'auto';
                app.miradasIzquierdaLbl.Text = MIZQ;
            else
                warning('UITableResultadosIzquierda component not found or invalid.');
            end

            %% --- Tabla Derecha ---
            if isprop(app, 'UITableResultadosDerecha') && isvalid(app.UITableResultadosDerecha)
                d = datos_derecha(2:end);
                tabla_der = [
                    d(4)  d(1)  d(7);
                    d(5)  d(2)  d(8);
                    d(6)  d(3)  d(9)
                    ];
                tabla_der_cell = num2cell(tabla_der);

                globalsDer = {'rangeDer','rmsDer','latMeanDer','latStdDer'};
                extraCols = repmat({''},3,numel(globalsDer));
                for i = 1:numel(globalsDer)
                    if ismember(globalsDer{i}, patient_data.Properties.VariableNames)
                        v = patient_data.(globalsDer{i});
                    else
                        v = NaN;
                    end
                    if ~isnan(v)
                        switch globalsDer{i}
                            case 'rangeDer'
                                if v < 100
                                    extraCols{3,i} = v;
                                elseif v <= 250
                                    extraCols{2,i} = v;
                                else
                                    extraCols{1,i} = v;
                                end
                            case 'rmsDer'
                                if v < 50
                                    extraCols{3,i} = v;
                                elseif v <= 100
                                    extraCols{2,i} = v;
                                else
                                    extraCols{1,i} = v;
                                end
                            case 'latMeanDer'
                                if v < 200
                                    extraCols{1,i} = v;
                                elseif v <= 300
                                    extraCols{2,i} = v;
                                else
                                    extraCols{3,i} = v;
                                end
                            case 'latStdDer'
                                if v < 30
                                    extraCols{1,i} = v;
                                elseif v <= 60
                                    extraCols{2,i} = v;
                                else
                                    extraCols{3,i} = v;
                                end
                        end
                    end
                end

                % Columnas compartidas duplicadas
                extraShared = repmat({''},3,numel(sharedGlobals));
                for i = 1:numel(sharedGlobals)
                    if ismember(sharedGlobals{i}, patient_data.Properties.VariableNames)
                        v = patient_data.(sharedGlobals{i});
                    else
                        v = NaN;
                    end
                    if ~isnan(v)
                        switch sharedGlobals{i}
                            case 'symIndex'
                                if v < 0.33
                                    extraShared{3,i} = v;
                                elseif v <= 0.66
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                            case 'velRatio'
                                if v < 0.8
                                    extraShared{3,i} = v;
                                elseif v <= 1.2
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                            case 'ampRatio'
                                if v < 0.8
                                    extraShared{3,i} = v;
                                elseif v <= 1.2
                                    extraShared{2,i} = v;
                                else
                                    extraShared{1,i} = v;
                                end
                        end
                    end
                end

                tabla_der_ext = [tabla_der_cell, extraCols, extraShared];
                colNames_ext = [colNames, "Rango P2P (µV)","RMS (µV)","Latencia media (s)","Latencia STD (s)", "Indice Simetria %","Ratio Velocidad","Ratio Amplitud"];

                app.UITableResultadosDerecha.Data = tabla_der_ext;
                app.UITableResultadosDerecha.ColumnName = colNames_ext;
                app.UITableResultadosDerecha.RowName = rowNames;
                app.UITableResultadosDerecha.ColumnWidth = 'auto';
                app.miradasDerechaLbl.Text = MDER;
            else
                warning('UITableResultadosDerecha component not found or invalid.');
            end
            % --- Actualizar ObservacionesLBL ---
            if isprop(app, 'ObservacionesLBL') && isvalid(app.ObservacionesLBL)
                obsText = "";

                obsFields = {'velObs','ampObs','durObs','dirObs'};
                obsLabels = {'Velocidad','Amplitud','Duración','Dirección'};

                for k = 1:numel(obsFields)
                    field = obsFields{k};
                    if ismember(field, patient_data.Properties.VariableNames) && ...
                            ~isempty(patient_data.(field){1}) && ~strcmp(patient_data.(field){1},"")
                        obsText = obsText + "• " + obsLabels{k} + ": " + patient_data.(field){1} + newline + newline;
                    end
                end

                % Quitar el salto de línea final si existe
                if strlength(obsText) > 0 && obsText(end) == newline
                    obsText = extractBefore(obsText,strlength(obsText));
                end

                app.ObservacionesLBL.Text = obsText;
            else
                warning('ObservacionesLBL no existe o no es válido.');
            end

        end

        function CalculateFatigue(app,JOINTABLES)
            %% --- Izquierda ---
            % Calcular IFO
            movTotalizq = JOINTABLES.miradasIzq;
            maxMovIzq = max(movTotalizq);
            movNormIzq = zeros(size(movTotalizq));
            if maxMovIzq ~= 0
                movNormIzq = (movTotalizq ./ maxMovIzq) * 100;
            end

            ampScoreIzq = 100 - JOINTABLES.ampLowPct_Izq;
            velScoreIzq = 100 - JOINTABLES.velLowPct_Izq;
            durScoreIzq = 100 - JOINTABLES.durLongPct_Izq;
            IFOIzq = (movNormIzq + ampScoreIzq + velScoreIzq + durScoreIzq)/4;

            if IFOIzq >= 70
                FatigueLevelIzq = "Baja";
            elseif IFOIzq >= 40
                FatigueLevelIzq = "Moderada";
            else
                FatigueLevelIzq = "Alta";
            end

            % --- Insertar en tabla como columna nueva ---
            if isprop(app, 'UITableResultadosIzquierda') && isvalid(app.UITableResultadosIzquierda)
                data = app.UITableResultadosIzquierda.Data;
                if ~iscell(data)
                    data = num2cell(data);  % asegurar que es cell
                end

                % Agregar columna vacía
                nRows = size(data,1);
                newCol = repmat({''}, nRows,1);

                % Asignar valor a fila correcta
                switch FatigueLevelIzq
                    case "Alta"
                        newCol{1} = 100-IFOIzq;
                    case "Moderada"
                        newCol{2} = 100-IFOIzq;
                    case "Baja"
                        newCol{3} = 100-IFOIzq;
                end

                % Concatenar columna
                data = [data, newCol];
                app.UITableResultadosIzquierda.Data = data;

                % Nombre de la columna
                app.UITableResultadosIzquierda.ColumnName{end+1} = 'Fatigue_Lvl (IFO Score)';
            end

            %% --- Derecha ---
            movTotalder = JOINTABLES.miradasDer;
            maxMovDer = max(movTotalder);
            movNormDer = zeros(size(movTotalder));
            if maxMovDer ~= 0
                movNormDer = (movTotalder ./ maxMovDer) * 100;
            end

            ampScoreDer = 100 - JOINTABLES.ampLowPct_Der;
            velScoreDer = 100 - JOINTABLES.velLowPct_Der;
            durScoreDer = 100 - JOINTABLES.durLongPct_Der;
            IFODer = (movNormDer + ampScoreDer + velScoreDer + durScoreDer)/4;

            if IFODer >= 70
                FatigueLevelDer = "Baja";
            elseif IFODer >= 40
                FatigueLevelDer = "Moderada";
            else
                FatigueLevelDer = "Alta";
            end

            % --- Insertar en tabla como columna nueva ---
            if isprop(app, 'UITableResultadosDerecha') && isvalid(app.UITableResultadosDerecha)
                data = app.UITableResultadosDerecha.Data;
                if ~iscell(data)
                    data = num2cell(data);
                end

                nRows = size(data,1);
                newCol = repmat({''}, nRows,1);

                switch FatigueLevelDer
                    case "Alta"
                        newCol{1} = 100-IFODer;
                    case "Moderada"
                        newCol{2} = 100-IFODer;
                    case "Baja"
                        newCol{3} = 100-IFODer;
                end

                data = [data, newCol];
                app.UITableResultadosDerecha.Data = data;
                app.UITableResultadosDerecha.ColumnName{end+1} = 'Fatigue_Lvl (IFO Score)';
            end
            if FatigueLevelIzq == "Alta"
                FatigueMessageIzq = ...
                    "Según el movimiento ocular registrado, el paciente presenta un índice alto de fatiga músculo-ocular, mostrando un rendimiento reducido durante la prueba. Esto puede indicar una recuperación lenta o dificultad para mantener un control oculomotor sostenido.";

            elseif FatigueLevelIzq == "Moderada"
                FatigueMessageIzq = ...
                    "Según el patrón de movimiento del ojo, el paciente muestra un nivel moderado de fatiga. Aunque mantiene parte del rendimiento, se observa cierta disminución en la amplitud, velocidad o número de movimientos hacia el final de la prueba.";

            else  % Fatiga Baja
                FatigueMessageIzq = ...
                    "El movimiento ocular del paciente sugiere un nivel bajo de fatiga, con un rendimiento estable durante la tarea y sin signos relevantes de deterioro del control oculomotor.";
            end

            if FatigueLevelDer == "Alta"
                FatigueMessageDer = ...
                    "Según el movimiento ocular registrado, el paciente presenta un índice alto de fatiga músculo-ocular, mostrando un rendimiento reducido durante la prueba. Esto puede indicar una recuperación lenta o dificultad para mantener un control oculomotor sostenido.";

            elseif FatigueLevelDer == "Moderada"
                FatigueMessageDer = ...
                    "Según el patrón de movimiento del ojo, el paciente muestra un nivel moderado de fatiga. Aunque mantiene parte del rendimiento, se observa cierta disminución en la amplitud, velocidad o número de movimientos hacia el final de la prueba.";

            else  % Fatiga Baja
                FatigueMessageDer = ...
                    "El movimiento ocular del paciente sugiere un nivel bajo de fatiga, con un rendimiento estable durante la tarea y sin signos relevantes de deterioro del control oculomotor.";
            end

            MessF = "Fatiga Mirada Izquierda: " +  FatigueMessageIzq  + newline + newline + "Fatiga Mirada Derecha: " +  FatigueMessageDer ;
            app.FatigaLabel.Text = MessF;
        end
        function drawArrows(app,axs, tBlock, sigBlock)

            % Si aún no hay eventos calculados, salimos
            if isempty(app.EventLocations) || isempty(app.EventDirections)
                return;
            end

            % Frecuencia de muestreo (la misma que usas en el resto del código)
            fs = 500;

            % Convertir tiempo del bloque a índices globales
            idxStart = round(tBlock(1)*fs) + 1;
            idxEnd   = round(tBlock(end)*fs) + 1;

            % Encontrar eventos que caen dentro del bloque
            idxEvents = find(app.EventLocations >= idxStart & app.EventLocations <= idxEnd);

            if isempty(idxEvents)
                return;
            end
            if axs == 1
                hold(app.intro,'on');
            elseif axs == 2
                hold(app.test,'on');
            elseif axs == 3
                hold(app.rest,'on');
            elseif axs == 4
                hold(app.ExpandedPlot,'on');
            end

            for k = 1:numel(idxEvents)
                i   = idxEvents(k);
                idx = app.EventLocations(i);   % índice global ESCALAR
                dir = app.EventDirections(i);  % 1 = derecha, -1 = izquierda
                fprintf("Valor = %.2f\n", dir);
                % Índice local dentro de este bloque (forzamos a escalar)
                localIdx = round(idx - idxStart + 1);

                % Comprobamos que esté dentro de los límites del bloque
                if localIdx < 1 || localIdx > numel(sigBlock)
                    continue;
                end

                % Coordenadas del punto donde dibujar la flecha (ESCALARES)
                x = tBlock(localIdx);
                y = sigBlock(localIdx);

                % Componentes de la flecha en X e Y (ESCALARES)
                U = 0.1 * dir;   % flecha hacia la derecha (+) o izquierda (-)
                V = 0;
                if dir == 1
                    mark = "^";
                    fill = "red";
                elseif dir == -1
                    mark = "v";
                    fill = "blue";
                end
                if axs == 1
                    % Ahora X, Y, U, V son todos 1x1 -> quiver no se queja
                    quiver(app.intro, x, y, U, V , LineStyle= "none", AutoScale = 'off' , Marker = mark, MarkerFaceColor=fill,MarkerEdgeColor="none");
                elseif axs == 2
                    % Ahora X, Y, U, V son todos 1x1 -> quiver no se queja
                    quiver(app.test, x, y, U, V, LineStyle= "none",AutoScale = 'off' , Marker = mark, MarkerFaceColor=fill,MarkerEdgeColor="none");
                elseif axs == 3
                    % Ahora X, Y, U, V son todos 1x1 -> quiver no se queja
                    quiver(app.rest, x, y, U, V, LineStyle= "none", AutoScale = 'off' , Marker = mark, MarkerFaceColor=fill,MarkerEdgeColor="none");
                elseif axs == 4
                    quiver(app.ExpandedPlot, x, y, U, V, LineStyle= "none", AutoScale = 'off' , Marker = mark, MarkerFaceColor=fill,MarkerEdgeColor="none");
                end

            end
        end
        function drawEventLimits(app, tBlock)
            % Convierte el rango temporal en índices globales
            fs = 500;
            idxStart = round(tBlock(1)*fs) + 1;
            idxEnd   = round(tBlock(end)*fs) + 1;

            % Busca los eventos dentro del bloque
            idxEvents = find(app.EventLocations >= idxStart & app.EventLocations <= idxEnd);

            if isempty(idxEvents)
                return;
            end

            hold(app.ExpandedPlot,'on');

            for i = idxEvents
                s = app.EventStartIdx(i);
                e = app.EventEndIdx(i);

                % Convertimos a tiempo
                t_s = (s-1)/fs;
                t_e = (e-1)/fs;

                % Dibujar líneas verticales
                xline(app.ExpandedPlot, t_s, '--m', 'LineWidth', 1.2);  % inicio (magenta)
                xline(app.ExpandedPlot, t_e, '--c', 'LineWidth', 1.2);  % final (cyan)
            end
        end

        function indices = getIndicesByTipo(app, tipo)
            nBlocks = length(app.blocks);
            indices = [];

            switch tipo
                case 'All'
                    indices = 1:nBlocks;
                    return
                case 'Tipo 1'
                    tipoNum = 1;
                case 'Tipo 2'
                    tipoNum = 2;
            end

            for i = 0:(nBlocks/3 - 1)
                if mod(i,2) == tipoNum-1
                    indices = [indices, i*3 + (1:3)];
                end
            end
        end
        function UpdateAnalysis(app, tipo)
            % Si no se pasa tipo, usamos el valor actual del dropdown
            if nargin < 2
                tipo = app.AnalisisTypeDropDown.Value;
            end

            if tipo == "All"
                % Restaurar la señal completa
                app.EOG = app.EOG_full;
            else
                % Filtrar bloques según Tipo 1 o 2
                nBlocks = length(app.blocks);
                indices = [];
                tipoNum = 1*(tipo=="Tipo 1") + 2*(tipo=="Tipo 2");

                for i = 0:(nBlocks/3 - 1)
                    if mod(i,2) == tipoNum-1
                        indices = [indices, i*3 + (1:3)];
                    end
                end

                % Concatenar sub-bloques seleccionados
                EOG_all = [];
                for k = indices
                    EOG_all = [EOG_all; app.blocks(k).sig{1}(:); app.blocks(k).sig{2}(:); app.blocks(k).sig{3}(:)];
                end
                app.EOG = EOG_all;
            end

            % ---- Cálculos posteriores ----
            T_summary = calculateEOGMetrics(app, app.f, app.PatientID);
            UpdateResultsTable(app, T_summary);
            CalculateFatigue(app, T_summary);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Panel1 visible, Panel2 oculto
            % Get screen size
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;

            app.Mainmenu.Visible = 'on';
            app.SubplotsWindow.Visible = 'off';
            app.AnalisisWindow.Visible = "off";
            app.EyesWindow.Visible = 'off';
            app.EventsAnalisisWindow.Visible = 'off';
            ApplyTheme(app);

            app.EYEL_initialPos = app.EYEL.Position;
            app.EYER_initialPos = app.EYER.Position;
        end

        % Button pushed function: CargardatosButton
        function LoadData(app, event)
            % Obtiene la carpeta donde está la app
            appFolder = fileparts(mfilename('fullpath'));

            % Construye la ruta de la subcarpeta 'data'
            initialFolder = fullfile(appFolder, 'edffiles');

            % Diálogo para seleccionar archivo .edf, con carpeta inicial
            [file, path] = uigetfile('*.edf', 'Seleccione un archivo edf', initialFolder);

            % Si el usuario cancela
            if isequal(file, 0)
                disp('El usuario canceló la operación.');
            else
                % Crea la ruta completa del archivo
                fullFileName = fullfile(path, file);
                loading = uiprogressdlg(app.UIFigure, ...
                    'Title', 'Loading...', ...
                    'Message', 'Please wait while loading data...', ...
                    'Indeterminate', 'on');  % spinning bar
                drawnow  % ensure dialog appears immediately
                % Carga el archivo
                data = edfread(fullFileName);

                % Extrae nombre y extensión del archivo
                [~, fileName, ext] = fileparts(fullFileName);

                % Muestra el nombre en un campo de texto del app
                app.Label.Text = [fileName, ext];

                appPath = fileparts(mfilename('fullpath'));
                tsvPath = fullfile(appPath,'participants.tsv');

                if isfile(tsvPath)
                    app.ParticipantsTable = readtable(tsvPath, 'FileType','text','Delimiter','\t');
                else
                    warning("No se encontró participants.tsv en: " + appPath);
                    app.ParticipantsTable = table();
                end
                app.ParticipantsTable.Properties.VariableNames
                % --- Convert text columns to string ---
                textCols = {'Participant_ID','Gender','ParalysisSide','Handedness','IsFirstTime','StrokeLocation'};
                for c = 1:numel(textCols)
                    colName = textCols{c};
                    if iscell(app.ParticipantsTable.(colName))
                        app.ParticipantsTable.(colName) = string(app.ParticipantsTable.(colName));
                    end
                end

                % --- Ensure numeric columns are numeric ---
                numericCols = {'Age','Duration','NIHSS','MBI','mRS'};
                for c = 1:numel(numericCols)
                    colName = numericCols{c};
                    if iscell(app.ParticipantsTable.(colName))
                        app.ParticipantsTable.(colName) = cellfun(@str2double, app.ParticipantsTable.(colName));
                    end
                end
                % ---- 2. Extraer ID del archivo (ej: 'sub-01') ----
                tokens = regexp(fileName, '(sub-\d+)', 'match');
                if ~isempty(tokens)
                    app.PatientID = string(tokens{1});
                else
                    app.PatientID = "unknown";
                end

                % ---- 3. Buscar ese ID en participants.tsv ----
                if ~isempty(app.ParticipantsTable)

                    ids = string(app.ParticipantsTable.Participant_ID);
                    idx = find(ids == app.PatientID, 1);   % posición de sub-01

                    if ~isempty(idx)
                        row = app.ParticipantsTable(idx, :);

                        app.PatientGender    = string(row.Gender);
                        app.PatientAge       = row.Age;
                        app.PatientParalysis = string(row.ParalysisSide);
                        app.PatientNIHSS = row.NIHSS;
                        app.PatientMBI   = row.MBI;
                        app.PatientmRS   = row.mRS;

                    else
                        % No se encontró el ID en la tabla
                        app.PatientGender    = "unknown";
                        app.PatientAge       = NaN;
                        app.PatientParalysis = "unknown";
                        app.PatientNIHSS     = NaN;
                        app.PatientMBI       = NaN;
                        app.PatientmRS       = NaN;
                    end
                else
                    % La tabla está vacía
                    app.PatientGender    = "unknown";
                    app.PatientAge       = NaN;
                    app.PatientParalysis = "unknown";
                    app.PatientNIHSS     = NaN;
                    app.PatientMBI       = NaN;
                    app.PatientmRS       = NaN;
                end

                % Actualizar TextArea con los datos del paciente
                UpdatePatientInfo(app);


                sigL = vertcat(data.HEOL{:});
                sigR = vertcat(data.HEOR{:});
                app.EOG_full = sigL - sigR;  % señal completa original
                app.EOG = app.EOG_full;      % por defecto
                fs = 500;
                t = (0:length(app.EOG)-1)/fs;
                app.f = fs;
                plot(app.Mainplot,t,sigL)
                hold(app.Mainplot, 'on');
                plot(app.Mainplot,t,sigR)
                hold(app.Mainplot, 'off');
                xlim(app.Mainplot, [t(1) t(end)]);

                close(loading);
                app.DataLoaded = true;
                app.DataButton.BackgroundColor = [194 24 58] / 255;
                segLengths = [2, 4, 2] * fs;  % [2s, 4s, 2s] in samples
                totalBlockLength = sum(segLengths);  % 8*fs samples
                nBlocks = floor(length(sigL)/totalBlockLength);
                block(nBlocks) = struct('sig', [], 't', []);

                for k = 1:nBlocks
                    startIdx = (k-1)*totalBlockLength + 1;

                    % Extract the full block
                    blockSig = app.EOG(startIdx : startIdx + totalBlockLength - 1);
                    blockT   = t(startIdx : startIdx + totalBlockLength - 1);

                    % Split the block into the 3 subarrays
                    idx1 = 1 : segLengths(1);
                    idx2 = segLengths(1)+1 : sum(segLengths(1:2));
                    idx3 = sum(segLengths(1:2))+1 : totalBlockLength;

                    block(k).sig = { blockSig(idx1), blockSig(idx2), blockSig(idx3) };
                    block(k).t   = { blockT(idx1), blockT(idx2), blockT(idx3) };
                end
                app.blocks = block;
                % ===== Inicializar Tipo de Prueba =====
                if ~isempty(app.TipoPruebasDropDown.Items)
                    app.TipoPruebasDropDown.Value = app.TipoPruebasDropDown.Items{1};
                end

                % Forzar filtrado inicial de pruebas
                TipoPruebasValueChanged(app, []);
                UpdateAnalysis(app);

            end
        end

        % Button pushed function: DataButton
        function ChangeWindow(app, event)
            if ~app.DataLoaded
                uialert(app.UIFigure, 'You must load the data first!', 'Warning');
                return;  % stop execution
            end
            app.Mainmenu.Visible = 'off';
            app.SubplotsWindow.Visible = 'on';
            nBlocks = length(app.blocks);
            dropdownItems = arrayfun(@(k) ['Prueba' num2str(k)], 1:nBlocks, 'UniformOutput', false);
            app.PruebasDropDown.Items = dropdownItems;
            app.PruebasDropDown.Value = dropdownItems{1}; % poner la primera como seleccionada
            ChoosePrueba(app, event)
            if ~isempty(app.TipoPruebasDropDown.Items)
                app.TipoPruebasDropDown.Value = app.TipoPruebasDropDown.Items{1};  % selecciona el primer item
                TipoPruebasValueChanged(app, event);
            end
        end

        % Value changed function: PruebasDropDown
        function ChoosePrueba(app, event)
            selected = app.PruebasDropDown.Value;

            % Obtener número de prueba seleccionada
            n = str2double(extractAfter(selected, 'Prueba'));
            app.index = n;

            t1 = app.blocks(n).t{1};
            s1 = app.blocks(n).sig{1};
            t2 = app.blocks(n).t{2};
            s2 = app.blocks(n).sig{2};
            t3 = app.blocks(n).t{3};
            s3 = app.blocks(n).sig{3};

            % Plot normal
            app.Introplot = plot(app.intro, t1, s1); hold(app.intro,'on');
            app.Testplot  = plot(app.test,  t2, s2); hold(app.test,'on');
            app.Restplot  = plot(app.rest,  t3, s3); hold(app.rest,'on');

            % === AÑADIR FLECHAS POR BLOQUE ===
            drawArrows(app, 1, t1, s1);
            drawArrows(app, 2,  t2, s2);
            drawArrows(app, 3,  t3, s3);

            hold(app.intro,'off');
            hold(app.test,'off');
            hold(app.rest,'off');
        end

        % Button pushed function: IntroButton
        function ExpandedIntro(app, event)
            ExpandedView(app,0)
        end

        % Button pushed function: TestButton
        function ExpandedTest(app, event)
            ExpandedView(app,1)
        end

        % Button pushed function: RestButton
        function ExpandedRest(app, event)
            ExpandedView(app,2)
        end

        % Button pushed function: AnalisisButton
        function AnalisisButtonPushed(app, event)

            app.SubplotsWindow.Visible = 'off';
            app.AnalisisWindow.Visible = 'on';

            UpdatePatientInfo(app);
        end

        function UpdatePatientInfo(app)

            % Valores por defecto si están vacíos
            if isempty(app.PatientID)
                app.PatientID = "unknown";
            end
            if isempty(app.PatientGender)
                app.PatientGender = "unknown";
            end
            if isempty(app.PatientAge)
                app.PatientAge = NaN;
            end
            if isempty(app.PatientParalysis)
                app.PatientParalysis = "unknown";
            end
            if isempty(app.PatientNIHSS)
                app.PatientNIHSS = NaN;
            end
            if isempty(app.PatientMBI)
                app.PatientMBI = NaN;
            end
            if isempty(app.PatientmRS)
                app.PatientmRS = NaN;
            end

            % Creamos un ARRAY DE STRINGS (no celdas)
            info = [
                "Paciente: "       + string(app.PatientID)
                "Gender: "         + string(app.PatientGender)
                "Age: "            + string(app.PatientAge)
                "Paralysis side: " + string(app.PatientParalysis)
                "NIHSS: "          + string(app.PatientNIHSS)
                "MBI: "            + string(app.PatientMBI)
                "mRS: "            + string(app.PatientmRS)
                ];

            % Lo escribimos en el Text Area
            app.PatientInfoLabel.Text = info;
        end

        % Button pushed function: GoBackButtonEyes
        function GoBackButtonEyesPushed(app, event)
            app.EyesWindow.Visible = 'off';
            app.SubplotsWindow.Visible = 'on';
        end

        function ApplyTheme(app)
            % --- Colores base ---
            backgroundColor = [32 38 46] / 255;
            panelColor      = [244 246 248] / 255;
            buttonColor     = [194 24 58] / 255;
            dropdownColor   = [42 58 79] / 255;
            textColor       = [230 232 235] / 255;
            whiteText       = [1 1 1];
            redButton       = [196 109 131] / 255; % color especial DataButton

            % --- UIFigure y paneles ---
            figAndPanels = [...
                app.Mainmenu;
                app.AnalisisWindow;
                app.EyesWindow;
                app.SubplotsWindow;
                app.EventsAnalisisWindow;
                app.EyePanelL;
                app.EyePanelR;
                app.MainMenuGrid;
                app.AnalisisGrid;
                app.EyesGrid;
                app.SubplotsGrid;
                app.EventsAnalisisGrid];
            app.UIFigure.Color = backgroundColor;
            for u = figAndPanels'
                u.BackgroundColor = backgroundColor;
            end

            % --- Dropdowns ---
            dropdowns = [
                app.PruebasDropDown;
                app.TipoPruebasDropDown;
                app.AnalisisTypeDropDown];
            for d = dropdowns'
                d.BackgroundColor = dropdownColor;
                d.FontColor = textColor;
            end

            % --- Botones ---
            botones = [...
                app.CargardatosButton;
                app.IntroButton;
                app.TestButton;
                app.RestButton;
                app.AnalisisButton;
                app.GoBackButtonEyes;
                app.GoBackButtonAnalisis;
                app.ExportButton;
                app.PlayButton;
                app.GoBackButtonEvents;
                app.EventsAnalisisButton];

            for b = botones'
                b.BackgroundColor = buttonColor;
                b.FontColor = whiteText;
                b.FontWeight = 'bold';
            end
            % Botón especial
            app.DataButton.BackgroundColor = redButton;

            % --- Axes ---
            axesList = [
                app.Mainplot;
                app.intro;
                app.test;
                app.rest;
                app.ExpandedPlot];

            for ax = axesList'
                ax.Color = panelColor;
                ax.XGrid = 'off';
                ax.YGrid = 'off';

                % Título y ejes
                ax.Title.Color = textColor;
                ax.XColor = textColor;
                ax.YColor = textColor;

                % Tick labels
                if isprop(ax.XAxis, 'TickLabelColor')
                    ax.XAxis.TickLabelColor = textColor;
                    ax.YAxis.TickLabelColor = textColor;
                end
            end

            % --- Labels ---
            labels = [...
                app.ANLISISPACIENTELabel;
                app.EventsAnalisisLBL;
                app.TotalEventsLBL;
                app.Label;
                app.TypeAnalisisLabel;
                app.miradasDerechaLbl;
                app.miradasIzquierdaLbl;
                app.DATOSDELPACIENTELabel;
                app.FATIGAname;
                app.PatientInfoLabel;
                app.ObservacionesLBL;
                app.ObservacionesNamelbl;
                app.FatigaLabel];

            for lbl = labels'
                lbl.FontColor = textColor;
            end

            % --- Botones "volver" y EventsAnalisisButton (texto blanco) ---
            whiteTextButtons = [app.GoBackButtonAnalisis; app.GoBackButtonEyes; app.GoBackButtonEvents; app.EventsAnalisisButton];
            for b = whiteTextButtons'
                b.FontColor = whiteText;
            end

        end

        % Button pushed function: GoBackButtonAnalisis
        function GoBackAnalisisPushed(app, event)
            app.AnalisisWindow.Visible = 'off';
            app.SubplotsWindow.Visible = 'on';
        end

        % Button pushed function: ExportButton
        function ExportClicked(app, event)
            % === 0. Diálogo para elegir el fichero ===
            [file, path] = uiputfile('*.tsv', 'Guardar análisis como TSV');
            if isequal(file,0)
                return; % usuario canceló
            end
            fullpath = fullfile(path, file);

            % Abrir archivo para escritura
            fid = fopen(fullpath,'w');
            if fid == -1
                uialert(app.UIFigure,"No se pudo abrir el archivo para escritura.","Error","Icon","error");
                return;
            end

            % Tipos de análisis
            tipos = ["Tipo 1", "Tipo 2", "All"];

            %% === 1. DATOS DEL PACIENTE ===
            fprintf(fid, '=== DATOS DEL PACIENTE ===\n');
            infoPaciente = cellstr(app.PatientInfoLabel.Text);
            for i = 1:numel(infoPaciente)
                fprintf(fid, '%s\n', infoPaciente{i});
            end
            fprintf(fid, '\n');

            %% === 2. Analisis por tipo ===
            for t = 1:numel(tipos)
                tipo = tipos(t);

                % Actualizar análisis para este tipo
                app.AnalisisTypeDropDown.Value = tipo;
                UpdateAnalysis(app);  % recalcula EOG, métricas y fatiga

                % --- IZQUIERDA ---
                fprintf(fid, '=== RESULTADOS OJO IZQUIERDO (%s) ===\n', tipo);
                fprintf(fid, '%s\n\n', app.miradasIzquierdaLbl.Text);

                dataL = app.UITableResultadosIzquierda.Data;
                colL  = app.UITableResultadosIzquierda.ColumnName;
                rowL  = app.UITableResultadosIzquierda.RowName;

                if isnumeric(dataL)
                    dataL = num2cell(dataL);
                end
                if isempty(rowL)
                    rowL = repmat({''}, size(dataL,1), 1);
                end

                % Cabecera
                fprintf(fid,'\t');
                for j = 1:numel(colL)
                    fprintf(fid,'%s\t', colL{j});
                end
                fprintf(fid,'\n');

                % Filas
                for r = 1:size(dataL,1)
                    fprintf(fid,'%s\t', rowL{r});
                    for c = 1:size(dataL,2)
                        val = dataL{r,c};
                        if isnumeric(val)
                            fprintf(fid,'%g\t', val);
                        else
                            fprintf(fid,'%s\t', val);
                        end
                    end
                    fprintf(fid,'\n');
                end
                fprintf(fid,'\n');

                % --- DERECHA ---
                fprintf(fid, '=== RESULTADOS OJO DERECHO (%s) ===\n', tipo);
                fprintf(fid, '%s\n\n', app.miradasDerechaLbl.Text);

                dataR = app.UITableResultadosDerecha.Data;
                colR  = app.UITableResultadosDerecha.ColumnName;
                rowR  = app.UITableResultadosDerecha.RowName;

                if isnumeric(dataR)
                    dataR = num2cell(dataR);
                end
                if isempty(rowR)
                    rowR = repmat({''}, size(dataR,1), 1);
                end

                % Cabecera
                fprintf(fid,'\t');
                for j = 1:numel(colR)
                    fprintf(fid,'%s\t', colR{j});
                end
                fprintf(fid,'\n');

                % Filas
                for r = 1:size(dataR,1)
                    fprintf(fid,'%s\t', rowR{r});
                    for c = 1:size(dataR,2)
                        val = dataR{r,c};
                        if isnumeric(val)
                            fprintf(fid,'%g\t', val);
                        else
                            fprintf(fid,'%s\t', val);
                        end
                    end
                    fprintf(fid,'\n');
                end
                fprintf(fid,'\n');

                % --- FATIGA ---
                fprintf(fid, '=== FATIGA (MENSAJE) (%s) ===\n', tipo);
                fatigaLines = cellstr(app.FatigaLabel.Text);
                for i = 1:numel(fatigaLines)
                    fprintf(fid,'%s\n', fatigaLines{i});
                end
                fprintf(fid,'\n');
                % --- TABLA DE EVENTOS SOLO SI TIPO = "All" ---
                if tipo == "All"
                    fprintf(fid, '=== TABLA DE EVENTOS (T_events) ===\n');

                    t_events = app.T_events;

                    % Cabecera de columnas
                    varNames = t_events.Properties.VariableNames;
                    for j = 1:numel(varNames)
                        fprintf(fid, '%s\t', varNames{j});
                    end
                    fprintf(fid, '\n');

                    % Filas de datos
                    for r = 1:height(t_events)
                        for c = 1:width(t_events)
                            val = t_events{r,c};
                            if isnumeric(val)
                                fprintf(fid,'%g\t', val);
                            elseif isstring(val) || ischar(val)
                                fprintf(fid,'%s\t', val);
                            else
                                fprintf(fid,'%s\t', string(val)); % Para otros tipos como categorical
                            end
                        end
                        fprintf(fid,'\n');
                    end
                    fprintf(fid,'\n');
                end
            end

            fclose(fid);
            uialert(app.UIFigure, "Datos exportados a TSV correctamente.", "Exportación completada", 'Icon', 'success');
        end

        % Value changed function: TipoPruebasDropDown
        function TipoPruebasValueChanged(app, event)
            tipoSeleccionado = app.TipoPruebasDropDown.Value;
            nBlocks = length(app.blocks);
            indices = [];

            if contains(tipoSeleccionado,'1')
                tipoNum = 1;
            else
                tipoNum = 2;
            end

            for i = 0:(nBlocks/3 - 1)
                if mod(i,2) == tipoNum-1
                    indices = [indices, i*3 + (1:3)];
                end
            end

            dropdownItems = arrayfun(@(k) ['Prueba' num2str(k)], indices, 'UniformOutput', false);
            app.PruebasDropDown.Items = dropdownItems;

            if ~isempty(dropdownItems)
                app.PruebasDropDown.Value = dropdownItems{1};
                ChoosePrueba(app, event)
            end

        end

        % Button pushed function: PlayButton
        function PlayPushed(app, event)
            n = app.index;
            p = app.ChosenPart;

            % Revisar si hay señal
            if isempty(app.blocks(n).sig{p})
                uialert(app.UIFigure,'No hay señal EOG cargada','Señal vacía');
                return
            end

            sig = app.blocks(n).sig{p};
            t   = app.blocks(n).t{p};

            maxStep   = 5;
            threshold = 0.02;

            % Límites panel
            figW_L = app.EyePanelL.Position(3);
            figW_R = app.EyePanelR.Position(3);
            % ===== MARGEN PROPORCIONAL =====
            marginRatio = 0.05;              % 5% del ancho del panel
            marginL = round(marginRatio * figW_L);
            marginR = round(marginRatio * figW_R);
            % Activar animación
            app.isPlaying = true;

            % Crear cursor si ExpandedView está activo
            if isprop(app,'ExpandedPlot') && app.ExpandedPlot.Visible
                hold(app.ExpandedPlot,'on');
                hCursor = plot(app.ExpandedPlot, t(1), sig(1), 'ro', ...
                    'MarkerFaceColor','r','MarkerSize',8);
            else
                hCursor = [];
            end

            % ===== Bucle de animación =====
            for i = 1:length(sig)

                % ---- CONTROL GLOBAL DE PARADA ----
                if ~app.isPlaying || ~app.EyesWindow.Visible
                    app.isPlaying = false;
                    break;
                end

                value = sig(i);

                % Filtrar ruido
                if abs(value) < threshold
                    value = 0;
                end

                valueNorm = value / max(abs(sig));
                deltaX = maxStep * valueNorm;

                % === MOVER OJO IZQUIERDO ===
                posL = app.EYEL.Position;
                posL(1) = posL(1) + deltaX;

                minX_L = marginL;
                maxX_L = figW_L - posL(3) - marginL;

                posL(1) = max(minX_L, min(maxX_L, posL(1)));
                app.EYEL.Position = posL;


                % === MOVER OJO DERECHO ===
                posR = app.EYER.Position;
                posR(1) = posR(1) + deltaX;

                minX_R = marginR;
                maxX_R = figW_R - posR(3) - marginR;

                posR(1) = max(minX_R, min(maxX_R, posR(1)));
                app.EYER.Position = posR;
                % === Cursor en gráfica ===
                if ~isempty(hCursor) && isvalid(hCursor)
                    hCursor.XData = t(i);
                    hCursor.YData = sig(i);
                end

                drawnow;

                if i < length(sig)
                    pause(t(i+1) - t(i));
                end
            end

            % ===== Reset al finalizar / interrumpir =====

            % Borrar cursor
            if ~isempty(hCursor) && isvalid(hCursor)
                delete(hCursor);
            end

            % Reset posiciones iniciales
            if isprop(app,'EYEL_initialPos')
                app.EYEL.Position = app.EYEL_initialPos;
                app.EYER.Position = app.EYER_initialPos;
            end

            % Indicar que ya no está reproduciendo
            app.isPlaying = false;

        end

        % Value changed function: AnalisisTypeDropDown
        function AnalisisTypeChanged(app, event)
            UpdateAnalysis(app);
            value = app.AnalisisTypeDropDown.Value;
            switch value
                case "All"
                    app.TypeAnalisisLabel.Text = "Total Analisis";
                    app.TotalEventsLBL.Text = "Todos los Eventos";
                case "Tipo 1"
                    app.TypeAnalisisLabel.Text = "Analisis Left Hand Movement Imagination";
                    app.TotalEventsLBL.Text = "Eventos Tipo 1";
                case "Tipo 2"
                    app.TypeAnalisisLabel.Text = "Analisis Right Hand Movement Imagination";
                    app.TotalEventsLBL.Text = "Eventos Tipo 2";
            end
        end

        % Button pushed function: GoBackButtonEvents
        function GoBackButtonEventsPushed(app, event)
            app.EventsAnalisisWindow.Visible = 'off';
            app.AnalisisWindow.Visible = 'on';
        end

        % Button pushed function: EventsAnalisisButton
        function EventsAnalisisPushed(app, event)
            app.AnalisisWindow.Visible = 'off';
            app.EventsAnalisisWindow.Visible = 'on';

            % Verifica que exista la tabla de eventos
            if isprop(app, 'EventsTable') && isvalid(app.EventsTable)
                if isprop(app, 'T_events') && ~isempty(app.T_events)
                    T = app.T_events;

                    % --- Definir unidades por columna ---
                    unidades = containers.Map( ...
                        {'EventID','StartTime','PeakTime','EndTime','Duration','Latency','PeakVel','Amplitude','Baseline', ...
                        'Direction','CatVel','CatAmp','CatDur'}, ...
                        {' ','(s)','(s)','(s)','(s)','(s)','(µV/s)','(µV)','(µV)',' ',' ',' ',' '} ...
                        );

                    % --- Convertir columnas string/categorical a cell y numéricas a cell ---
                    varNames = T.Properties.VariableNames;
                    for i = 1:numel(varNames)
                        if isstring(T.(varNames{i})) || iscategorical(T.(varNames{i}))
                            T.(varNames{i}) = cellstr(T.(varNames{i}));
                        elseif isnumeric(T.(varNames{i}))
                            if all(mod(T.(varNames{i}),1)==0)
                                T.(varNames{i}) = num2cell(T.(varNames{i}));
                            else
                                T.(varNames{i}) = num2cell(round(T.(varNames{i}),3));
                            end
                        end
                    end

                    data = table2cell(T);

                    % --- Agregar unidad al nombre de columna ---
                    colNames = varNames;
                    for i = 1:numel(varNames)
                        if unidades.isKey(varNames{i})
                            colNames{i} = sprintf('%s %s', varNames{i}, unidades(varNames{i}));
                        end
                    end

                    % --- Asignar datos y nombres a la tabla ---
                    app.EventsTable.Data = data;
                    app.EventsTable.ColumnName = colNames;
                    app.EventsTable.ColumnWidth = 'auto';

                    % --- Colorear filas según dirección ---
                    nRows = size(data,1);
                    for r = 1:nRows
                        sBG = uistyle;
                        sBG.FontColor = [0 0 0]; % texto negro

                        % Colores suaves por dirección
                        if strcmpi(T.Direction{r}, 'Izquierda')
                            sBG.BackgroundColor = [1 0.8 0.8]; % rojo suave
                        elseif strcmpi(T.Direction{r}, 'Derecha')
                            sBG.BackgroundColor = [0.8 0.9 1]; % azul suave
                        else
                            sBG.BackgroundColor = [1 1 1]; % blanco
                        end

                        addStyle(app.EventsTable, sBG, "row", r);
                    end

                else
                    warning('No hay datos de eventos disponibles en app.T_events.');
                    app.EventsTable.Data = {};
                    app.EventsTable.ColumnName = {};
                end
            else
                warning('El componente EventsTable no existe o no es válido.');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'winter');
            app.UIFigure.Name = 'MATLAB App';
            app.SubplotsWindow = uipanel(app.UIFigure);
            app.SubplotsGrid = uigridlayout(app.SubplotsWindow);
            app.rest = uiaxes(app.SubplotsGrid);
            title(app.rest, 'Rest (6-8s)')
            xlabel(app.rest, 'X')
            ylabel(app.rest, 'Y')
            zlabel(app.rest, 'Z')
            colormap(app.rest, 'winter')
            app.test = uiaxes(app.SubplotsGrid);
            title(app.test, 'Motor Imagery Test (2-6s)')
            xlabel(app.test, 'X')
            ylabel(app.test, 'Y')
            zlabel(app.test, 'Z')
            colormap(app.test, 'winter')
            app.intro = uiaxes(app.SubplotsGrid);
            title(app.intro, 'Introduction (0-2s)')
            xlabel(app.intro, 'X')
            ylabel(app.intro, 'Y')
            zlabel(app.intro, 'Z')
            colormap(app.intro, 'winter')
            app.RestButton = uibutton(app.SubplotsGrid, 'push');
            app.RestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedRest, true);
            app.RestButton.Text = 'Rest+';
            app.IntroButton = uibutton(app.SubplotsGrid, 'push');
            app.IntroButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedIntro, true);
            app.IntroButton.Text = 'Intro+';
            app.AnalisisButton = uibutton(app.SubplotsGrid, 'push');
            app.AnalisisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisisButtonPushed, true);
            app.AnalisisButton.Text = 'Analisis';
            app.TestButton = uibutton(app.SubplotsGrid, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedTest, true);
            app.TestButton.Text = 'Test+';
            app.PruebasDropDown = uidropdown(app.SubplotsGrid);
            app.PruebasDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePrueba, true);
            app.PruebasLabel = uilabel(app.SubplotsGrid);
            app.PruebasLabel.Text = 'Pruebas';
            app.TipoPruebasDropDown = uidropdown(app.SubplotsGrid);
            app.TipoPruebasDropDown.Items = {'Pruebas Tipo 1 (Left Hand Movement)', 'Pruebas Tipo 2 (Right Hand Movement)'};
            app.TipoPruebasDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoPruebasValueChanged, true);
            app.TipoPruebasDropDown.Value = 'Pruebas Tipo 1 (Left Hand Movement)';
            app.EyesWindow = uipanel(app.UIFigure);
            app.EyesGrid = uigridlayout(app.EyesWindow);
            app.ExpandedPlot = uiaxes(app.EyesGrid);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            colormap(app.ExpandedPlot, 'winter')
            app.GoBackButtonEyes = uibutton(app.EyesGrid, 'push');
            app.GoBackButtonEyes.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonEyesPushed, true);
            app.GoBackButtonEyes.Text = 'Go Back';
            app.EyePanelL = uipanel(app.EyesGrid);
            app.EYEL = uiimage(app.EyePanelL);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'IRISL.png');
            app.PlayButton = uibutton(app.EyesGrid, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPushed, true);
            app.PlayButton.Text = 'Play';
            app.EyePanelR = uipanel(app.EyesGrid);
            app.EYER = uiimage(app.EyePanelR);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'IRISR.png');
            app.Mainmenu = uipanel(app.UIFigure);
            app.MainMenuGrid = uigridlayout(app.Mainmenu);
            app.Mainplot = uiaxes(app.MainMenuGrid);
            title(app.Mainplot, 'Signal View')
            xlabel(app.Mainplot, 'X')
            ylabel(app.Mainplot, 'Y')
            zlabel(app.Mainplot, 'Z')
            colormap(app.Mainplot, 'winter')
            app.CargardatosButton = uibutton(app.MainMenuGrid, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.Label = uilabel(app.MainMenuGrid);
            app.Label.Text = '';
            app.DataButton = uibutton(app.MainMenuGrid, 'push');
            app.DataButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeWindow, true);
            app.DataButton.Text = 'Data';
            app.Image2 = uiimage(app.MainMenuGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'SEPALOGONAMEWHITE.png');
            app.AnalisisWindow = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisWindow);
            app.GoBackButtonAnalisis = uibutton(app.AnalisisGrid, 'push');
            app.GoBackButtonAnalisis.ButtonPushedFcn = createCallbackFcn(app, @GoBackAnalisisPushed, true);
            app.GoBackButtonAnalisis.Text = 'Go Back';
            app.UITableResultadosIzquierda = uitable(app.AnalisisGrid);
            app.UITableResultadosIzquierda.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosIzquierda.RowName = {};
            app.ANLISISPACIENTELabel = uilabel(app.AnalisisGrid);
            app.ANLISISPACIENTELabel.Text = 'ANÁLISIS PACIENTE';
            app.Image = uiimage(app.AnalisisGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CUERPOF.png');
            app.DATOSDELPACIENTELabel = uilabel(app.AnalisisGrid);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.FATIGAname = uilabel(app.AnalisisGrid);
            app.FATIGAname.Text = 'FATIGA:';
            app.PatientInfoLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel.Text = 'Label2';
            app.UITableResultadosDerecha = uitable(app.AnalisisGrid);
            app.UITableResultadosDerecha.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosDerecha.RowName = {};
            app.miradasIzquierdaLbl = uilabel(app.AnalisisGrid);
            app.miradasIzquierdaLbl.Text = 'Izq';
            app.miradasDerechaLbl = uilabel(app.AnalisisGrid);
            app.miradasDerechaLbl.Text = 'Der';
            app.ExportButton = uibutton(app.AnalisisGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportClicked, true);
            app.ExportButton.Text = 'Export to .tsv';
            app.AnalisisTypeDropDown = uidropdown(app.AnalisisGrid);
            app.AnalisisTypeDropDown.Items = {'All', 'Tipo 1', 'Tipo 2'};
            app.AnalisisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalisisTypeChanged, true);
            app.AnalisisTypeDropDown.Value = 'All';
            app.TypeAnalisisLabel = uilabel(app.AnalisisGrid);
            app.TypeAnalisisLabel.Text = 'Total Analisis';
            app.EventsAnalisisButton = uibutton(app.AnalisisGrid, 'push');
            app.EventsAnalisisButton.ButtonPushedFcn = createCallbackFcn(app, @EventsAnalisisPushed, true);
            app.EventsAnalisisButton.Text = 'Events Analisis';
            app.ObservacionesNamelbl = uilabel(app.AnalisisGrid);
            app.ObservacionesNamelbl.Text = 'OBSERVACIONES:';
            app.ObservacionesLBL = uilabel(app.AnalisisGrid);
            app.ObservacionesLBL.Text = 'Label2';
            app.EventsAnalisisWindow = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisWindow);
            app.GoBackButtonEvents = uibutton(app.EventsAnalisisGrid, 'push');
            app.GoBackButtonEvents.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonEventsPushed, true);
            app.GoBackButtonEvents.Text = 'Go Back';
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.TotalEventsLBL = uilabel(app.EventsAnalisisGrid);
            app.TotalEventsLBL.Text = 'Todos los Eventos';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sepa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end